classdef NDN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NatDyNetUIFigure             matlab.ui.Figure
        Panel_2                      matlab.ui.container.Panel
        GMMethodButton               matlab.ui.control.Button
        ROIMethodButton              matlab.ui.control.Button
        GMvoxelMethodsDropDown       matlab.ui.control.DropDown
        GMvoxelMethodsDropDownLabel  matlab.ui.control.Label
        ROIMethodsDropDown           matlab.ui.control.DropDown
        ROIMethodsDropDownLabel      matlab.ui.control.Label
        STEP2ChoosemethodLabel       matlab.ui.control.Label
        Panel                        matlab.ui.container.Panel
        STEP1AcquireROITCLabel       matlab.ui.control.Label
        ExtractROITimeCourseButton   matlab.ui.control.Button
        ISDCCButton_5                matlab.ui.control.Button
        ISDCCButton_4                matlab.ui.control.Button
        ISDCCButton_3                matlab.ui.control.Button
        SFCButton                    matlab.ui.control.Button
        NatDyNetNatureDynamicsNetworkLabel  matlab.ui.control.Label
        ISCAPButton                  matlab.ui.control.Button
        ISCButton                    matlab.ui.control.Button
    end


    properties (Access = public)
        ISCapp % Description
        ISCAPapp
        SFCapp
        GetROITCapp
        SWFCapp
        ISSWFCapp
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Welcome to using NatDyNet--Nature Dynamics Network toolbox')
        end

        % Close request function: NatDyNetUIFigure
        function NatDyNetUIFigureCloseRequest(app, event)
            delete(app.ISCAPapp)
            delete(app.ISCapp)
            delete(app.SFCapp)
            delete(app.GetROITCapp)
            delete(app)
            disp('Thank you for using Nature Dynamics Network')
        end

        % Button pushed function: ExtractROITimeCourseButton
        function ExtractROITimeCourseButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ExtractROITimeCourseButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.GetROITCapp = GetROITC_GUI(app);
        end

        % Button pushed function: ISCButton
        function ISCButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.ISCButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.ISCapp = ISC_GUI(app);
        end

        % Button pushed function: SFCButton
        function SFCButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.SFCButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.SFCapp = SFCROI_GUI(app);
        end

        % Button pushed function: ISCAPButton
        function ISCAPButtonPushed(app, event)
                        % Disable Plot Options button while dialog is open
            app.ISCAPButton.Enable = 'off';

            % Open the options dialog and pass inputs
            app.ISCapp = ISCAP_GUI(app);
        end

        % Value changed function: GMvoxelMethodsDropDown
        function GMvoxelMethodsDropDownValueChanged(app, event)
%             value = app.GMvoxelMethodsDropDown.Value;
%             if ~isequal(value, ' ')
%                 app.GMvoxelMethodsDropDown.Enable = "off";
%             end
%             if isequal(value, 'ISCAP')
%                 app.ISCAPapp = ISCAP_GUI(app);
%             end
%             if isequal(value, 'ISC')
%                 app.ISCapp = ISC_GUI(app);
%             end
        end

        % Value changed function: ROIMethodsDropDown
        function ROIMethodsDropDownValueChanged(app, event)
%             value = app.ROIMethodsDropDown.Value;
%             if ~isequal(value, ' ')
%                 app.ROIMethodsDropDown.Enable = "off";
%             end
%             if isequal(value, 'SFC')
%                 app.SFCapp = SFCROI_GUI(app);
%             end
        end

        % Button pushed function: GMMethodButton
        function GMMethodButtonPushed(app, event)
            value = app.GMvoxelMethodsDropDown.Value;
            if ~isequal(value, ' ')
                app.GMvoxelMethodsDropDown.Enable = "off";
                app.GMMethodButton.Enable = "off";
            else
                return
            end

            if isequal(value, 'ISCAP')
                app.ISCAPapp = ISCAP_GUI(app);
            end
            if isequal(value, 'ISC')
                app.ISCapp = ISC_GUI(app);
            end
            
        end

        % Button pushed function: ROIMethodButton
        function ROIMethodButtonPushed(app, event)
            value = app.ROIMethodsDropDown.Value;

            if ~isequal(value, ' ')
                app.ROIMethodsDropDown.Enable = "off";
                app.ROIMethodButton.Enable = "off";
            else
                return
            end

            if isequal(value, 'SFC')
                app.SFCapp = SFCROI_GUI(app);
            end
            if isequal(value, 'SWFC')
                app.SWFCapp = ISSWFC_GUI(app, 'Sliding-window functional connectivity(SWFC)', 'SWFC');
            end
            if isequal(value, 'ISSWFC')
                app.ISSWFCapp = ISSWFC_GUI(app, 'Intersubject sliding-window functional connectivity(ISSWFC)', 'ISSWFC');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.NatDyNetUIFigure = uifigure('Visible', 'off');
            app.NatDyNetUIFigure.Name = 'NatDyNet';
            app.NatDyNetUIFigure.CloseRequestFcn = createCallbackFcn(app, @NatDyNetUIFigureCloseRequest, true);
            app.ISCButton = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISCButton.ButtonPushedFcn = createCallbackFcn(app, @ISCButtonPushed, true);
            app.ISCButton.Text = 'Intersubject Correlation(ISC)';
            app.ISCAPButton = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISCAPButton.ButtonPushedFcn = createCallbackFcn(app, @ISCAPButtonPushed, true);
            app.ISCAPButton.Text = 'Intersubject Co-activation Pattern(ISCAP)';
            app.NatDyNetNatureDynamicsNetworkLabel = uilabel(app.NatDyNetUIFigure);
            app.NatDyNetNatureDynamicsNetworkLabel.Text = 'NatDyNet--Nature Dynamics Network';
            app.SFCButton = uibutton(app.NatDyNetUIFigure, 'push');
            app.SFCButton.ButtonPushedFcn = createCallbackFcn(app, @SFCButtonPushed, true);
            app.SFCButton.Text = 'Static Funtional Connectivity(SFC)';
            app.ISDCCButton_3 = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISDCCButton_3.Text = 'Intersubject Dynamic Condition Correlation';
            app.ISDCCButton_4 = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISDCCButton_4.Text = 'Intersubject Funtional Connectivity(ISFC)';
            app.ISDCCButton_5 = uibutton(app.NatDyNetUIFigure, 'push');
            app.ISDCCButton_5.Text = 'Dynamics InterSubject Correlation(DISC)';
            app.Panel = uipanel(app.NatDyNetUIFigure);
            app.ExtractROITimeCourseButton = uibutton(app.Panel, 'push');
            app.ExtractROITimeCourseButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractROITimeCourseButtonPushed, true);
            app.ExtractROITimeCourseButton.Text = 'Extract ROI Time Course';
            app.STEP1AcquireROITCLabel = uilabel(app.Panel);
            app.STEP1AcquireROITCLabel.Text = 'STEP 1:  Acquire ROI TC';
            app.Panel_2 = uipanel(app.NatDyNetUIFigure);
            app.STEP2ChoosemethodLabel = uilabel(app.Panel_2);
            app.STEP2ChoosemethodLabel.Text = 'STEP 2:  Choose method';
            app.ROIMethodsDropDownLabel = uilabel(app.Panel_2);
            app.ROIMethodsDropDownLabel.Text = 'ROI Methods:';
            app.ROIMethodsDropDown = uidropdown(app.Panel_2);
            app.ROIMethodsDropDown.Items = {'SFC', 'SWFC', 'ISSWFC', ' '};
            app.ROIMethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIMethodsDropDownValueChanged, true);
            app.ROIMethodsDropDown.Value = ' ';
            app.GMvoxelMethodsDropDownLabel = uilabel(app.Panel_2);
            app.GMvoxelMethodsDropDownLabel.Text = 'GM voxel Methods:';
            app.GMvoxelMethodsDropDown = uidropdown(app.Panel_2);
            app.GMvoxelMethodsDropDown.Items = {'ISC', 'ISCAP', ' '};
            app.GMvoxelMethodsDropDown.ValueChangedFcn = createCallbackFcn(app, @GMvoxelMethodsDropDownValueChanged, true);
            app.GMvoxelMethodsDropDown.Value = ' ';
            app.ROIMethodButton = uibutton(app.Panel_2, 'push');
            app.ROIMethodButton.ButtonPushedFcn = createCallbackFcn(app, @ROIMethodButtonPushed, true);
            app.ROIMethodButton.Text = 'choose';
            app.GMMethodButton = uibutton(app.Panel_2, 'push');
            app.GMMethodButton.ButtonPushedFcn = createCallbackFcn(app, @GMMethodButtonPushed, true);
            app.GMMethodButton.Text = 'choose';
            % Show the figure after all components are created
            app.NatDyNetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NDN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NatDyNetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NatDyNetUIFigure)
        end
    end
end